classdef model_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIModelUIFigure              matlab.ui.Figure
        GenerateCertificatesButton    matlab.ui.control.Button
        MailCertificatesButton        matlab.ui.control.Button
        ImageTemplateFileEditFieldLabel  matlab.ui.control.Label
        ImageTemplateFileEditField    matlab.ui.control.EditField
        RegistrationDatasetEditField  matlab.ui.control.EditField
        RegistrationDatasetEditFieldLabel  matlab.ui.control.Label
        SubjectEditField              matlab.ui.control.EditField
        MessageEditField              matlab.ui.control.EditField
        SubjectEditFieldLabel         matlab.ui.control.Label
        CertificateGeneratorandDistributionModelLabel  matlab.ui.control.Label
        MessageEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        v1
        v2
        subj
        msg % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateCertificatesButton
        function GenerateCertificatesButtonPushed(app, event)
             app.v1 = app.RegistrationDatasetEditField.Value;
             app.v2 = app.ImageTemplateFileEditField.Value;
             if (isempty(app.msg) || isempty(app.subj))
                 certificate_generator('Registration_Details.xls', 'Certificate_Blank.tif');
             else
                 certificate_generator(app.v1, app.v2);
             end   
            %certificate_generator();
        end

        % Button pushed function: MailCertificatesButton
        function MailCertificatesButtonPushed(app, event)
            app.subj = app.SubjectEditField.Value;
            app.msg = app.MessageEditField.Value;
            if (isempty(app.msg) || isempty(app.subj))
                mail_module('Webinar Certificate', 'Thankyou for attending the session, we hope you found the session to be resourceful. Please find your certificate as an attachment');
            else
                mail_module(app.subj, app.msg);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIModelUIFigure = uifigure('Visible', 'off');
            app.GUIModelUIFigure.Name = 'GUI Model';
            app.GenerateCertificatesButton = uibutton(app.GUIModelUIFigure, 'push');
            app.GenerateCertificatesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCertificatesButtonPushed, true);
            app.GenerateCertificatesButton.Tag = 'certi_gen_button';
            app.GenerateCertificatesButton.Text = 'Generate Certificates';
            app.MailCertificatesButton = uibutton(app.GUIModelUIFigure, 'push');
            app.MailCertificatesButton.ButtonPushedFcn = createCallbackFcn(app, @MailCertificatesButtonPushed, true);
            app.MailCertificatesButton.Tag = 'certi_mail_button';
            app.MailCertificatesButton.Text = 'Mail Certificates';
            app.ImageTemplateFileEditFieldLabel = uilabel(app.GUIModelUIFigure);
            app.ImageTemplateFileEditFieldLabel.Text = 'Image Template File: ';
            app.ImageTemplateFileEditField = uieditfield(app.GUIModelUIFigure, 'text');
            app.ImageTemplateFileEditField.Tag = 'imgTempField';
            app.ImageTemplateFileEditField.Value = 'Text';
            app.RegistrationDatasetEditField = uieditfield(app.GUIModelUIFigure, 'text');
            app.RegistrationDatasetEditField.Tag = 'regDataField';
            app.RegistrationDatasetEditField.Value = 'Text';
            app.RegistrationDatasetEditFieldLabel = uilabel(app.GUIModelUIFigure);
            app.RegistrationDatasetEditFieldLabel.Text = 'Registration Dataset: ';
            app.SubjectEditField = uieditfield(app.GUIModelUIFigure, 'text');
            app.SubjectEditField.Value = 'Text';
            app.MessageEditField = uieditfield(app.GUIModelUIFigure, 'text');
            app.MessageEditField.Value = 'Text';
            app.SubjectEditFieldLabel = uilabel(app.GUIModelUIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject: ';
            app.CertificateGeneratorandDistributionModelLabel = uilabel(app.GUIModelUIFigure);
            app.CertificateGeneratorandDistributionModelLabel.Text = {'Certificate Generator'; 'and'; 'Distribution Model'};
            app.MessageEditFieldLabel = uilabel(app.GUIModelUIFigure);
            app.MessageEditFieldLabel.Text = 'Message: ';
            % Show the figure after all components are created
            app.GUIModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIModelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIModelUIFigure)
        end
    end
end